classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TahminSonucuLabel    matlab.ui.control.Label
        OlaslkDeeriLabel     matlab.ui.control.Label
        TahminYapButton      matlab.ui.control.Button
        RntgenGrntsSeButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RntgenGrntsSeButton
        function RntgenGrntsSeButtonPushed(app, event)
              [fileName, pathName] = uigetfile({'*.jpg;*.jpeg;*.png','Görüntü Dosyaları'}, 'Röntgen Görüntüsü Seç');
        if fileName ~= 0
            selectedImage = fullfile(pathName, fileName);
            img = imread(selectedImage);
            imshow(img, 'Parent', app.UIAxes); % Görüntüyü Görüntüleme Alanında Göster
            app.RntgenGrntsSeButton.UserData.selectedImage = img;
        
        end
        end

        % Button pushed function: TahminYapButton
        function TahminYapButtonPushed(app, event)
           if isfield(app.RntgenGrntsSeButton.UserData, 'selectedImage')
               try
                   %Kaydedilmiş Modeli Yükle
                   loadedModel = load('egitilmisZaturreModeli.mat');
                   trainedNetwork = loadedModel.netTransfer;
                   % 2. Seçilen Görüntüyü Modelin Giriş Boyutuna Getir
                   inputSize = trainedNetwork.Layers(1).InputSize;
                   img = app.RntgenGrntsSeButton.UserData.selectedImage;
                   img = imresize(img, inputSize(1:2));
                   % Siyah-beyaz görüntüyü RGB'ye dönüştür
                   if size(img, 3) == 1
                       img = cat(3, img, img, img);
                   end
                   % 3. Tahmin Yap
                   predictedLabel = classify(trainedNetwork, img);
                   app.TahminSonucuLabel.Text = ['Sonuç: ', char(predictedLabel)]; % Tahmin Sonucunu Göster

                   % 4. İsteğe Bağlı: Olasılık Değerini Göster
                   [~, scores] = classify(trainedNetwork, img);
                   probability = max(scores);
                   app.OlaslkDeeriLabel.Text = ['Olasılık: ', num2str(probability)];
                catch ME
                    app.TahminSonucuLabel.Text = ['Hata: ', ME.message];
                    app.OlaslkDeeriLabel.Text = ['Olasılık Hata: ', ME.message];
                end
           else
               app.TahminSonucuLabel.Text = 'Önce bir görüntü seçiniz';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'imageArea';
            app.RntgenGrntsSeButton = uibutton(app.UIFigure, 'push');
            app.RntgenGrntsSeButton.ButtonPushedFcn = createCallbackFcn(app, @RntgenGrntsSeButtonPushed, true);
            app.RntgenGrntsSeButton.Tag = 'browseBtn';
            app.RntgenGrntsSeButton.Text = 'Röntgen Görüntüsü Seç';
            app.TahminYapButton = uibutton(app.UIFigure, 'push');
            app.TahminYapButton.ButtonPushedFcn = createCallbackFcn(app, @TahminYapButtonPushed, true);
            app.TahminYapButton.Tag = 'predictBtn';
            app.TahminYapButton.Text = 'Tahmin Yap';
            app.OlaslkDeeriLabel = uilabel(app.UIFigure);
            app.OlaslkDeeriLabel.Tag = 'probabilityLabel';
            app.OlaslkDeeriLabel.Text = 'Olasılık Değeri:';
            app.TahminSonucuLabel = uilabel(app.UIFigure);
            app.TahminSonucuLabel.Tag = 'resultLabel';
            app.TahminSonucuLabel.Text = 'Tahmin Sonucu:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end