classdef app3Squeeze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SonuLabel            matlab.ui.control.Label
        OlaslkLabel          matlab.ui.control.Label
        TahminYapButton      matlab.ui.control.Button
        RntgenGrntsSeButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RntgenGrntsSeButton
        function RntgenGrntsSeButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg;*.jpeg;*.png','Görüntü Dosyaları'}, 'Röntgen Görüntüsü Seç');
    
    if isequal(fileName, 0)
        return; % Dosya seçilmediyse çık
    end

    selectedImage = fullfile(pathName, fileName);
    img = imread(selectedImage);
    imshow(img, 'Parent', app.UIAxes); % Resmi UIAxes içinde göster
    app.RntgenGrntsSeButton.UserData.selectedImage = img; % Resmi hafızaya al
        
    
        end

        % Button pushed function: TahminYapButton
        function TahminYapButtonPushed(app, event)
            if isfield(app.RntgenGrntsSeButton.UserData, 'selectedImage')
            try
            % 1. SqueezeNet modelini yükle
            loadedModel = load('SqueezeNet.mat');
            trainedNetwork = loadedModel.netTransfer;  % Model adı kesin doğru

            % 2. Görüntüyü hazırla
            inputSize = trainedNetwork.Layers(1).InputSize;
            img = app.RntgenGrntsSeButton.UserData.selectedImage;
            img = imresize(img, inputSize(1:2));

            % 3. Gri ise RGB'ye çevir
            if size(img, 3) == 1
                img = cat(3, img, img, img);
            end

            % 4. Tahmin yap
            [predictedLabel, scores] = classify(trainedNetwork, img);
            probability = max(scores);

            % 5. Sonuçları göster
            app.SonuLabel.Text = ['Sonuç: ', char(predictedLabel)];
            app.OlaslkLabel.Text = ['Olasılık: ', num2str(probability)];

        catch ME
            app.SonuLabel.Text = ['Hata: ', ME.message];
            app.OlaslkLabel.Text = ['Olasılık Hata: ', ME.message];
        end
        else
        app.SonuLabel.Text = 'Önce bir görüntü seçiniz';
            end
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RntgenGrntsSeButton = uibutton(app.UIFigure, 'push');
            app.RntgenGrntsSeButton.ButtonPushedFcn = createCallbackFcn(app, @RntgenGrntsSeButtonPushed, true);
            app.RntgenGrntsSeButton.Text = 'Röntgen Görüntüsü Seç	';
            app.TahminYapButton = uibutton(app.UIFigure, 'push');
            app.TahminYapButton.ButtonPushedFcn = createCallbackFcn(app, @TahminYapButtonPushed, true);
            app.TahminYapButton.Text = 'Tahmin Yap';
            app.OlaslkLabel = uilabel(app.UIFigure);
            app.OlaslkLabel.Text = 'Olasılık: ';
            app.SonuLabel = uilabel(app.UIFigure);
            app.SonuLabel.Text = 'Sonuç: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3Squeeze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end